classdef face < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        fixpartsButton       matlab.ui.control.Button
        MosaicButton         matlab.ui.control.Button
        stylizedButton       matlab.ui.control.Button
        GaussianNoiseButton  matlab.ui.control.Button
        PartsButton          matlab.ui.control.Button
        GETPCAFACEButton     matlab.ui.control.Button
        recognizeButton      matlab.ui.control.Button
        chooseoneButton      matlab.ui.control.Button
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        im % 存储图像数据
        reference % 参考数据集
        W % PCA 权重矩阵或特征空间
        imgmean % 训练集图像的平均图像
        col_of_data % 训练集中图像的数量
        pathname % 训练集和测试集图像的文件路径
        img_path_list % 训练集和测试集图像的文件列表
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: chooseoneButton
        function chooseoneButtonPushed(app, event)
        % 弹出文件选择对话框让用户选择图片
        [filename, pathname] = uigetfile({'*.bmp'}, 'Choose photo');
        if isequal(filename, 0) || isequal(pathname, 0)
            return; % 用户取消操作
        end
        fullFilePath = fullfile(pathname, filename);
        
        % 读取并显示图像
        app.im = imread(fullFilePath);
        imshow(app.im, 'Parent', app.UIAxes); % 使用 Axes 控件显示图像
        % 或者如果有一个 Image 控件叫做 'Image'
        % app.Image.ImageSource = app.im;
        end

        % Button pushed function: recognizeButton
        function trainthemachineButtonPushed(app, event)
        % 预处理新数据
        app.im
        imVector = double(app.im(:));
        objectone = app.W' * (imVector - app.imgmean);
        distance = inf; 
        aimone = 1;
        % 最小距离法，寻找和待识别图片最为接近的训练图片
        for k = 1:app.col_of_data
            temp = norm(objectone - app.reference(:, k));
            if distance > temp
                distance = temp;
                aimone = k;
            end
        end
        % 获取匹配图像的路径
        aimpath = fullfile(app.pathname, app.img_path_list(aimone).name);
        % 显示测试结果
        % 使用Axes 控件来显示图像
        imshow(imread(aimpath), 'Parent', app.UIAxes2);
        % 如果有一个 Image 控件用于显示结果，例如 app.AimImage
        % app.AimImage.ImageSource = imread(aimpath);
        end

        % Button pushed function: GETPCAFACEButton
        function GETPCAFACEButtonPushed2(app, event)
        % 批量读取指定文件夹下的图片
        app.pathname = uigetdir; % 保存路径作为 app 的属性
        if app.pathname == 0
            return; 
        end
        app.img_path_list = dir(fullfile(app.pathname, '*.bmp')); % 使用 fullfile 保证跨平台兼容性
        img_num = length(app.img_path_list);
        imagedata = [];
        if img_num > 0
            for j = 1:img_num
                img_name = app.img_path_list(j).name;
                temp = imread(fullfile(app.pathname, img_name));
                temp = double(temp(:));
                imagedata = [imagedata, temp];
            end
        end
        app.col_of_data = size(imagedata, 2); % 更新 col_of_data 属性
    
        % 中心化 & 计算协方差矩阵
        app.imgmean = mean(imagedata, 2); % 更新 imgmean 属性
        for i = 1:app.col_of_data
            imagedata(:, i) = imagedata(:, i) - app.imgmean;
        end
        covMat = imagedata' * imagedata;
        [COEFF, ~, explained] = pcacov(covMat);
    
        % 选择构成95%的特征值
        i = 1;
        proportion = 0;
        while proportion < 95
            proportion = proportion + explained(i);
            i = i + 1;
        end
        p = i - 1;
    
        % 特征脸
        app.W = imagedata * COEFF(:, 1:p); % 更新 W 属性
    
        % 训练样本在新座标基下的表达矩阵
        app.reference = app.W' * imagedata; % 更新 reference 属性
        end

        % Callback function
        function accuracyButtonPushed(app, event)
        % 选择测试集
        testPath = uigetdir;
        if testPath == 0
            return; % 用户取消操作
        end
        testImgList = dir(fullfile(testPath, '*.bmp'));
        testNum = length(testImgList);
        testData = [];
        cendata = [];
        if testNum > 0
            for j = 1:testNum
                imgName = testImgList(j).name;
                tempImage = imread(fullfile(testPath, imgName));
                tempImage = double(tempImage(:));
                testData = [testData, tempImage];
            end
        end
        colOfTest = size(testData, 2);
        meandata = mean(testData,2);
        for i = 1:size(testData,2)
            cendata(:,i) = testData(:,i) - meandata;
        end
         
        % 然后进行矩阵乘法
        object = app.W' * cendata;
    
        % 计算分类器准确率
        numCorrect = 0;
        for j = 1:colOfTest
            distance = Inf;
            for k = 1:app.col_of_data
                tempDist = norm(object(:, j) - app.reference(:, k));
                if distance > tempDist
                    aimOne = k;
                    distance = tempDist;
                end
            end
            if ceil(j/3) == ceil(aimOne/4)
                numCorrect = numCorrect + 1;
            end
        end
        accuracy = numCorrect / colOfTest;
        uialert(app.UIFigure, ['分类器准确率: ', num2str(accuracy)], 'Accuracy');
        end

        % Button pushed function: PartsButton
        function PartsButtonPushed(app, event)
         % 选择文件夹
        folder = uigetdir;
        if folder == 0
            return; % 用户取消操作
        end
    
        % 获取文件夹中的所有图片文件
        imageFiles = dir(fullfile(folder, '*.bmp')); 
        if isempty(imageFiles)
            uialert(app.UIFigure, 'No PNG images found in the selected folder.', 'No Images');
            return;
        end
    
        % 检查'photo'文件夹是否存在，如果不存在，则创建
        outputFolder = fullfile(folder, 'photo');
        if ~exist(outputFolder, 'dir')
            mkdir(outputFolder);
        end
    
        for idx = 1:length(imageFiles)
            % 读取每一张图片
            filename = fullfile(imageFiles(idx).folder, imageFiles(idx).name);
            image = imread(filename);
    
            % 检查图片大小并裁剪、填充
            if all(size(image) == [128, 128])
                % 裁剪到60x128
                croppedImage = image(:, 68:127);
    
                % 填充到128x128
                paddingSize = (128 - 60) / 2;
                paddedImage = padarray(croppedImage, [0 paddingSize], 0, 'both');
    
                % 保存处理后的图片
                [~, name, ext] = fileparts(filename);
                outputFilename = fullfile(outputFolder, [name, '_processed', ext]);
                imwrite(paddedImage, outputFilename);

           
            end
        end

        end

        % Button pushed function: GaussianNoiseButton
        function GaussianNoiseButtonPushed(app, event)
         % 用户选择文件夹
        folder = uigetdir;
        if folder == 0
            return; % 用户取消操作
        end
    
        % 获取文件夹中的所有图片文件
        imageFiles = dir(fullfile(folder, '*.bmp')); 
        if isempty(imageFiles)
            uialert(app.UIFigure, 'No PNG images found in the selected folder.', 'No Images');
            return;
        end
    
        % 创建输出文件夹 'photo2'
        outputFolder = fullfile(folder, 'photo2');
        if ~exist(outputFolder, 'dir')
            mkdir(outputFolder);
        end
    
        for idx = 1:length(imageFiles)
            filename = fullfile(imageFiles(idx).folder, imageFiles(idx).name);
            image = imread(filename);
    
            if all(size(image) == [128, 128])
                % 添加高斯噪声
                noisyImage = imnoise(image, 'gaussian');
    
                [~, name, ext] = fileparts(filename);
                outputFilename = fullfile(outputFolder, [name, '_noisy', ext]);
                imwrite(noisyImage, outputFilename);
            
            end
        end
        end

        % Button pushed function: stylizedButton
        function stylizedButtonPushed(app, event)
        % 用户选择文件夹
        folder = uigetdir;
        if folder == 0
            return; % 用户取消操作
        end
        
        % 获取文件夹中的所有图片文件
        imageFiles = dir(fullfile(folder, '*.bmp'));
        if isempty(imageFiles)
            uialert(app.UIFigure, 'No BMP images found in the selected folder.', 'No Images');
            return;
        end
        
        % 创建输出文件夹 'photo3'
        outputFolder = fullfile(folder, 'photo3');
        if ~exist(outputFolder, 'dir')
            mkdir(outputFolder);
        end
        
        for idx = 1:length(imageFiles)
            filename = fullfile(imageFiles(idx).folder, imageFiles(idx).name);
            image = imread(filename);
        
            if all(size(image, 1:2) == [128, 128])
                if size(image, 3) == 3
                    image = rgb2gray(image);
                end
        
                % 提取边缘轮廓
                edgeImage = edge(image, 'Canny');
        
                % 反转图像颜色
                edgeImage = 1 - edgeImage;
        
                [~, name, ext] = fileparts(filename);
                outputFilename = fullfile(outputFolder, [name, '_edges', ext]);
                imwrite(edgeImage, outputFilename);
            end
        end


        end

        % Button pushed function: MosaicButton
        function MosaicButtonPushed(app, event)
            % 用户选择文件夹
        folder = uigetdir;
        if folder == 0
            return; % 用户取消操作
        end
    
        % 获取文件夹中的所有图片文件
        imageFiles = dir(fullfile(folder, '*.bmp')); % 假设图片为PNG格式
        if isempty(imageFiles)
            uialert(app.UIFigure, 'No PNG images found in the selected folder.', 'No Images');
            return;
        end
    
        % 创建输出文件夹 'photo4'
        outputFolder = fullfile(folder, 'photo4');
        if ~exist(outputFolder, 'dir')
            mkdir(outputFolder);
        end
    
        for idx = 1:length(imageFiles)
            filename = fullfile(imageFiles(idx).folder, imageFiles(idx).name);
            image = imread(filename);
    
            if all(size(image, 1:2) == [128, 128])
                % 像素风格转换
                
                resizedImage = imresize(image, [32, 32], 'nearest'); 
                pixelImage = imresize(resizedImage, [128, 128], 'nearest'); 
    
                [~, name, ext] = fileparts(filename);
                outputFilename = fullfile(outputFolder, [name, '_pixel', ext]);
                imwrite(pixelImage, outputFilename);
            end
        end
        end

        % Button pushed function: fixpartsButton
        function fixpartsButtonPushed(app, event)
        folder = uigetdir;
        if folder == 0
            return; 
        end
    
        imageFiles = dir(fullfile(folder, '*.bmp'));
        if isempty(imageFiles)
            uialert(app.UIFigure, 'No BMP images found in the selected folder.', 'No Images');
            return;
        end
    
        outputFolder = fullfile(folder, 'photo5');
        if ~exist(outputFolder, 'dir')
            mkdir(outputFolder);
        end
    
        for idx = 1:length(imageFiles)
            filename = fullfile(imageFiles(idx).folder, imageFiles(idx).name);
            image = imread(filename);
    
            if all(size(image) == [128, 128])
                contentPart = image(:, 35:94); 
    
                % 重建整张图片（简单的插值方法）
                reconstructedImage = imresize(contentPart, [128, 128], 'bilinear');
    
                [~, name, ext] = fileparts(filename);
                outputFilename = fullfile(outputFolder, [name, '_reconstructed', ext]);
                imwrite(reconstructedImage, outputFilename);
    
            end
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'who he is？')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'he is ！')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.chooseoneButton = uibutton(app.UIFigure, 'push');
            app.chooseoneButton.ButtonPushedFcn = createCallbackFcn(app, @chooseoneButtonPushed, true);
            app.chooseoneButton.Text = 'choose one ';
            app.recognizeButton = uibutton(app.UIFigure, 'push');
            app.recognizeButton.ButtonPushedFcn = createCallbackFcn(app, @trainthemachineButtonPushed, true);
            app.recognizeButton.Text = 'recognize';
            app.GETPCAFACEButton = uibutton(app.UIFigure, 'push');
            app.GETPCAFACEButton.ButtonPushedFcn = createCallbackFcn(app, @GETPCAFACEButtonPushed2, true);
            app.GETPCAFACEButton.Text = 'GET PCA FACE';
            app.PartsButton = uibutton(app.UIFigure, 'push');
            app.PartsButton.ButtonPushedFcn = createCallbackFcn(app, @PartsButtonPushed, true);
            app.PartsButton.Text = 'Parts';
            app.GaussianNoiseButton = uibutton(app.UIFigure, 'push');
            app.GaussianNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianNoiseButtonPushed, true);
            app.GaussianNoiseButton.Text = 'Gaussian Noise';
            app.stylizedButton = uibutton(app.UIFigure, 'push');
            app.stylizedButton.ButtonPushedFcn = createCallbackFcn(app, @stylizedButtonPushed, true);
            app.stylizedButton.Text = 'stylized';
            app.MosaicButton = uibutton(app.UIFigure, 'push');
            app.MosaicButton.ButtonPushedFcn = createCallbackFcn(app, @MosaicButtonPushed, true);
            app.MosaicButton.Text = 'Mosaic';
            app.fixpartsButton = uibutton(app.UIFigure, 'push');
            app.fixpartsButton.ButtonPushedFcn = createCallbackFcn(app, @fixpartsButtonPushed, true);
            app.fixpartsButton.Text = 'fix parts';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = face

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end